classdef InvIPM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout10                    matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        BienvenidaTab                   matlab.ui.container.Tab
        GridLayout9                     matlab.ui.container.GridLayout
        HTML_Inicio                     matlab.ui.control.HTML
        ExploraciondealgoritmosTab      matlab.ui.container.Tab
        GridLayout27                    matlab.ui.container.GridLayout
        GridLayout28                    matlab.ui.container.GridLayout
        Panel                           matlab.ui.container.Panel
        GridLayout32                    matlab.ui.container.GridLayout
        CargarimagengroundtruthopcionalButton  matlab.ui.control.Button
        Image_2                         matlab.ui.control.Image
        CargarimagendepiezametlicaButton  matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        UIAxes_ground_truth             matlab.ui.control.UIAxes
        UIAxes_original                 matlab.ui.control.UIAxes
        GridLayout29                    matlab.ui.container.GridLayout
        GridLayout30                    matlab.ui.container.GridLayout
        Panel_2                         matlab.ui.container.Panel
        GridLayout31                    matlab.ui.container.GridLayout
        NmerodecentrosEditField         matlab.ui.control.NumericEditField
        NmerodecentrosLabel             matlab.ui.control.Label
        MtododeagrupamientoButtonGroup  matlab.ui.container.ButtonGroup
        InformacinespacialButton        matlab.ui.control.RadioButton
        GaussianMixturesButton          matlab.ui.control.RadioButton
        FuzzyCMeansButton               matlab.ui.control.RadioButton
        KMeansButton                    matlab.ui.control.RadioButton
        MtodoinvarianteButtonGroup      matlab.ui.container.ButtonGroup
        PCAButton                       matlab.ui.control.RadioButton
        UpcrofButton                    matlab.ui.control.RadioButton
        KrajnkButton                    matlab.ui.control.RadioButton
        MaddernButton                   matlab.ui.control.RadioButton
        AlvarezButton                   matlab.ui.control.RadioButton
        EjecutarButton                  matlab.ui.control.Button
        GuardarresultadosButton         matlab.ui.control.Button
        ResultadosPanel                 matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        UIAxes_invariante_segmentada    matlab.ui.control.UIAxes
        UIAxes_invariante_resultado     matlab.ui.control.UIAxes
        UIAxes_original_segmentada      matlab.ui.control.UIAxes
        UIAxes_original_resultado       matlab.ui.control.UIAxes
        CacheTab                        matlab.ui.container.Tab
        GridLayout11                    matlab.ui.container.GridLayout
        GridLayout33                    matlab.ui.container.GridLayout
        BorrarCacheButton               matlab.ui.control.Button
        AbrirdirectorioButton           matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        AyudaTab                        matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        Menu_Ayuda                      matlab.ui.container.TabGroup
        FuncionamientoTab               matlab.ui.container.Tab
        GridLayout26                    matlab.ui.container.GridLayout
        HTML_funcionamiento             matlab.ui.control.HTML
        MetodosInvariantesTab           matlab.ui.container.Tab
        GridLayout19                    matlab.ui.container.GridLayout
        TabInvariantesTipos             matlab.ui.container.TabGroup
        AlvarezTab                      matlab.ui.container.Tab
        GridLayout20                    matlab.ui.container.GridLayout
        HTML_Alvarez                    matlab.ui.control.HTML
        MaddernTab                      matlab.ui.container.Tab
        GridLayout21                    matlab.ui.container.GridLayout
        HTML_Maddern                    matlab.ui.control.HTML
        KrajnkTab                       matlab.ui.container.Tab
        GridLayout22                    matlab.ui.container.GridLayout
        HTML_Krajnik                    matlab.ui.control.HTML
        UpcrofTab                       matlab.ui.container.Tab
        GridLayout23                    matlab.ui.container.GridLayout
        HTML_Upcrof                     matlab.ui.control.HTML
        PCATab                          matlab.ui.container.Tab
        GridLayout25                    matlab.ui.container.GridLayout
        HTML_PCA                        matlab.ui.control.HTML
        MetodosAgrupamientoTab          matlab.ui.container.Tab
        GridLayout12                    matlab.ui.container.GridLayout
        TabAgrupamientoTipos            matlab.ui.container.TabGroup
        KMeansTab                       matlab.ui.container.Tab
        GridLayout13                    matlab.ui.container.GridLayout
        HTML_K_Means                    matlab.ui.control.HTML
        CMeansTab                       matlab.ui.container.Tab
        GridLayout14                    matlab.ui.container.GridLayout
        HTML_Fuzzy_C_Means              matlab.ui.control.HTML
        GMMTab                          matlab.ui.container.Tab
        GridLayout15                    matlab.ui.container.GridLayout
        HTML_GMM                        matlab.ui.control.HTML
        SpatialTab                      matlab.ui.container.Tab
        GridLayout16                    matlab.ui.container.GridLayout
        HTML_Spatial                    matlab.ui.control.HTML
        PorcentajeAciertoTab            matlab.ui.container.Tab
        GridLayout17                    matlab.ui.container.GridLayout
        HTML_Acierto                    matlab.ui.control.HTML
        MemoriaCacheTab                 matlab.ui.container.Tab
        GridLayout18                    matlab.ui.container.GridLayout
        HTML_Cache                      matlab.ui.control.HTML
        ContextMenu                     matlab.ui.container.ContextMenu
    end

    % Funciones auxiliares
    properties (Access = public)
        imagen
        imagen_ground_truth
        imagen_tres_canales
        nombre_imagen
        extension
        invariante
        agrupamiento
        numClusters
        imagen_original
        imagen_original_agrup
        imagen_invariante
        imagen_invariante_agrup
        porcentaje_original
        porcentaje_invariante
        tiene_img_ground_truth
        cachePath
    end

    methods (Access = private)
        function addImageToUITable(app, nombre, fecha, invariante, agrupamiento, numClusters, porcentaje)
            fecha = datetime(fecha, 'InputFormat', 'yyyyMMddHHmmss');
            
            % Si hay campos vacios
            if isempty(invariante)
                invariante = 'No aplicado';
            end

            if isempty(agrupamiento)
                agrupamiento = 'No aplicado';
            end

            if isempty(numClusters)
                numClusters = '-';
            end

            if isempty(porcentaje)
                medidaComparacion = 'No';
                valor_porcentaje = num2str(porcentaje);
            else
                medidaComparacion = 'Sí';
                valor_porcentaje = [num2str(porcentaje) '%'];
            end

            % Crear una nueva fila para la tabla
            newRow = {nombre, fecha, invariante, agrupamiento, num2str(numClusters), medidaComparacion, valor_porcentaje};

            % Convertir la nueva fila a una tabla
            newRowTable = cell2table(newRow, 'VariableNames', app.UITable.Data.Properties.VariableNames);

            % Actualizar la tabla
            app.UITable.Data = [app.UITable.Data; newRowTable];
        end

        function loadCacheTable(app)
            % Lista de archivos en el directorio de caché
            cacheFiles = dir(fullfile(app.cachePath, '*.*')); % Coincidir con cualquier archivo
            cacheFiles = cacheFiles(~ismember({cacheFiles.name}, {'.', '..'}));
            
            % Expresiones regulares para los tres tipos de archivos
            pattern1 = '(\d{14})_\d+__([^_]+(?:_[^_]+)*)__([^_]+)\.[a-zA-Z0-9]+';
            pattern2 = '(\d{14})_\d+__([^_]+(?:_[^_]+)*)__Original_([^_]+)_(\d+)_c_?(\d+\.\d+)?%?\.[a-zA-Z0-9]+';
            pattern3 = '(\d{14})_\d+__([^_]+(?:_[^_]+)*)__([^_]+)_([^_]+)_(\d+)_c_?(\d+\.\d+)?%?\.[a-zA-Z0-9]+';
            
            % Iterar sobre los archivos y extraer información
            for cacheFile = cacheFiles'
                fileName = cacheFile.name;
        
                % Para el patrón tipo 1
                match = regexp(fileName, pattern1, 'tokens');
                if ~isempty(match)
                    tokens = match{1};
                    fecha = tokens{1};
                    nombre = tokens{2};
                    inv = tokens{3};
                    addImageToUITable(app, nombre, fecha, inv, '', '', '');
                    continue;
                end
                
                % Para el patrón tipo 2
                match = regexp(fileName, pattern2, 'tokens');
                if ~isempty(match)
                    tokens = match{1};
                    fecha = tokens{1};
                    nombre = tokens{2};
                    agrup = tokens{3};
                    clusters = tokens{4};
                    porcentaje = '';
                    if length(tokens) > 4 && ~isempty(tokens{5})
                        porcentaje = tokens{5};
                    end
                    addImageToUITable(app, nombre, fecha, '', agrup, clusters, porcentaje);
                    continue;
                end
                
                % Para el patrón tipo 3
                match = regexp(fileName, pattern3, 'tokens');
                if ~isempty(match)
                    tokens = match{1};
                    fecha = tokens{1};
                    nombre = tokens{2};
                    inv = tokens{3};
                    agrup = tokens{4};
                    clusters = tokens{5};
                    porcentaje = '';
                    if length(tokens) > 5 && ~isempty(tokens{6})
                        porcentaje = tokens{6};
                    end
                    addImageToUITable(app, nombre, fecha, inv, agrup, clusters, porcentaje);
                    continue;
                end
            end
        end

        function bloqueoDesbloqueoOpciones(app, modo)
            if modo == 1
                % Bloqueo opciones
                app.MtodoinvarianteButtonGroup.Enable = "off";
                app.MtododeagrupamientoButtonGroup.Enable = "off";
                app.NmerodecentrosEditField.Enable = "off";
            else
                % Desbloqueo opciones
                app.MtodoinvarianteButtonGroup.Enable = "on";
                app.MtododeagrupamientoButtonGroup.Enable = "on";
                app.NmerodecentrosEditField.Enable = "on";
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Nombre de la app
            app.UIFigure.Name = 'Invariantes de Iluminación en piezas metálicas';

            % Obtener la resolución de la pantalla
            screenSize = get(0, 'ScreenSize'); % devuelve [left, bottom, width, height]
            
            % Obtener el tamaño de la ventana de la aplicación
            figSize = app.UIFigure.Position; % devuelve [left, bottom, width, height]
            
            % Calcular la nueva posición para centrar la ventana
            newX = (screenSize(3) - figSize(3)) / 2; % Centrado horizontal
            newY = (screenSize(4) - figSize(4)) / 2; % Centrado vertical
            
            % Ajustar la posición de la ventana de la aplicación
            app.UIFigure.Position = [newX, newY, figSize(3), figSize(4)];
            
            % Cargo las funciones
            raiz = fileparts(fileparts(app.UIFigure.FileName));
            functionsPath = fullfile(raiz, 'src', 'functions');
            addpath(functionsPath);

            % Creo/defino la cache
            % Ruta completa a la carpeta 'cache' dentro de 'results'
            app.cachePath = fullfile(raiz, 'results', 'cache');
        
            % Verificar si el directorio 'cache' existe
            if ~exist(app.cachePath, 'dir')
                mkdir(app.cachePath);
            end

            % Inicializa app.UITable.Data como una tabla vacía con las columnas correctas
            app.UITable.Data = table('Size', [0 7], ...
                                     'VariableTypes', {'string', 'datetime', 'string', 'string', 'string', 'string', 'string'}, ...
                                     'VariableNames', {'NombreFichero', 'Fecha', 'AlgoritmoInvariante', 'AlgoritmoAgrupamiento', 'NumeroCentros', 'ImagenGroundTruth', 'MedidaDeCalidad'});

            % Añado a la tabla de cache imagenes anteriores
            loadCacheTable(app);

            livescriptsPath = fullfile(raiz, 'src', 'livescripts');
            app.HTML_Inicio.HTMLSource = fullfile(livescriptsPath, 'bienvenida.html');
            app.HTML_funcionamiento.HTMLSource = fullfile(livescriptsPath, 'funcionamiento.html');
            app.HTML_Alvarez.HTMLSource = fullfile(livescriptsPath, 'met_inv_alvarez.html');
            app.HTML_Maddern.HTMLSource = fullfile(livescriptsPath, 'met_inv_maddern.html');
            app.HTML_Krajnik.HTMLSource = fullfile(livescriptsPath, 'met_inv_krajnik.html');
            app.HTML_Upcrof.HTMLSource = fullfile(livescriptsPath, 'met_inv_upcroft.html');
            app.HTML_PCA.HTMLSource = fullfile(livescriptsPath, 'met_inv_pca.html');
            app.HTML_K_Means.HTMLSource = fullfile(livescriptsPath, 'met_seg_kmeans.html');
            app.HTML_Fuzzy_C_Means.HTMLSource = fullfile(livescriptsPath, 'met_seg_cmeans.html');
            app.HTML_GMM.HTMLSource = fullfile(livescriptsPath, 'met_seg_gmm.html');
            app.HTML_Spatial.HTMLSource = fullfile(livescriptsPath, 'met_seg_spatial.html');
            app.HTML_Acierto.HTMLSource = fullfile(livescriptsPath, 'porcentaje_acierto.html');
            app.HTML_Cache.HTMLSource = fullfile(livescriptsPath, 'memoria_cache.html');

            % Oculto las graficas donde mostrare las imagenes solución
            app.UIAxes_original.Visible = "off";
            app.UIAxes_ground_truth.Visible = "off";
            app.UIAxes_original_resultado.Visible = "off";
            app.UIAxes_original_segmentada.Visible = "off";
            app.UIAxes_invariante_resultado.Visible = "off";
            app.UIAxes_invariante_segmentada.Visible = "off";

            % Inicialmente no se puede cargar una imagen ground truth, 
            % ni ejecutar ni guardar
            app.CargarimagengroundtruthopcionalButton.Enable = 'off';
            app.EjecutarButton.Enable = 'off';
            app.GuardarresultadosButton.Enable = 'off';

            % Tooltips
            app.CargarimagendepiezametlicaButton.Tooltip = 'Selecciona una imagen para aplicar los algoritmos de transformación invariante. Esta puede ser tanto del sistema como una propia.';
            app.CargarimagengroundtruthopcionalButton.Tooltip = 'Selecciona una imagen ground truth para comparar con la imagen seleccionada a la cual se la aplicarán algoritmos de transformación invariante.';
            app.EjecutarButton.Tooltip = 'Ejetutar algoritmos de trasformación invariante y agrupamiento seleccionados sobre la imagen selecionada.';
            app.GuardarresultadosButton.Tooltip = 'Guardar las cuatro imagenes solución en el directorio seleccionado. Estas tendran un nombre acorde a los resultados que han obtenido.';
            app.NmerodecentrosEditField.Tooltip = 'Selecciona entre 2 y 10 ya que 2 es el mínimo y a partir de 5 son ejecuciones muy largas por su alta complejidad computacional';
            app.AbrirdirectorioButton.Tooltip = 'Abrir el directorio donde se encuentran todas las imagenes guardadas en la caché';
        end

        % Button pushed function: CargarimagendepiezametlicaButton
        function CargarimagendepiezametlicaButtonPushed(app, event)
            % Bloqueo opciones
            bloqueoDesbloqueoOpciones(app, 1);

            app.CargarimagendepiezametlicaButton.Enable = "off";

            % Abro un cuadro de diálogo para seleccionar una imagen 
            % desde el directorio de imágenes predeterminado.
            raiz = fileparts(fileparts(app.UIFigure.FileName));
            directorio_origen = fullfile(raiz, 'data', 'Imagenes');
            directorio_ground_truth = fullfile(raiz, 'data', ...
                'Imagenes_ground_truth');
            [imagen_seleccionada, ruta] = uigetfile(fullfile( ...
                directorio_origen, '*.*'), 'Selecciona una imagen');
            
            % Vuelvo a la ventana de la aplicación
            figure(app.UIFigure);

            % Si se cancela el cuadro de diálogo
            if isequal(imagen_seleccionada, 0)
                % Desbloqueo opciones
                bloqueoDesbloqueoOpciones(app, 2);

                % Cambiar el texto del botón de carga de imagen
                app.CargarimagendepiezametlicaButton.Text = 'Cargar otra imagen';
                app.CargarimagendepiezametlicaButton.Enable = "on";
                uialert(app.UIFigure, ['Se ha cancelado la selección de imágenes.' ...
                    newline 'Carga una imagen para poder ejecutar.'], ...
                    'Selección de imagen cancelada');
                return;
            else
                % Indica si la imagen seleccionada tiene imagen ground truth 
                % asociada
                app.tiene_img_ground_truth = false;
                
                % Limpia las visualizaciones anteriores
                cla(app.UIAxes_original);
                cla(app.UIAxes_ground_truth);
                app.UIAxes_original.Visible = 'off';
                app.UIAxes_ground_truth.Visible = 'off';

                cla(app.UIAxes_original_resultado);
                cla(app.UIAxes_original_segmentada);
                cla(app.UIAxes_invariante_resultado);
                cla(app.UIAxes_invariante_segmentada);
                app.UIAxes_original_resultado.Visible = "off";
                app.UIAxes_original_segmentada.Visible = "off";
                app.UIAxes_invariante_resultado.Visible = "off";
                app.UIAxes_invariante_segmentada.Visible = "off";
                app.UIAxes_original_resultado.Title.String = '';
                app.UIAxes_original_segmentada.Title.String = '';
                app.UIAxes_invariante_resultado.Title.String = '';
                app.UIAxes_invariante_segmentada.Title.String = '';

                app.Image.Visible = 'on';
                app.Image_2.Visible = 'on';
                app.UIAxes_original.Title.String = '';
                app.UIAxes_ground_truth.Title.String = '';
                app.imagen = [];
                app.imagen_ground_truth= [];

                % Inicializar la waitbar
                h = waitbar(0, 'Cargando imagen...', 'Name', 'Por favor, espere');
            
                % Extraigo el nombre y la extensión de la imagen seleccionada.
                [~, app.nombre_imagen, app.extension] = fileparts(imagen_seleccionada);
                
                % Cambio en el texto del grafico de la imagen original
                texto_imagen_original = 'Imagen original';
                titleString = [texto_imagen_original ': ' app.nombre_imagen];
    
                % Evito el que lo interprete como LateX
                title(app.UIAxes_original, titleString, 'Interpreter', 'none');
    
                % Actualizar waitbar
                waitbar(0.3, h, 'Leyendo imagen...');
                
                % Leo la imagen desde su ruta completa.
                app.imagen = imread(fullfile(ruta, imagen_seleccionada));
                app.imagen_original = app.imagen;
                
                % Actualizar waitbar
                waitbar(0.6, h, 'Buscando imagen ground truth...');
                
                % Obtengo la ruta de la imagen ground truth en base a la 
                % seleccionada por el usuario
                posicion_inicio = strfind(ruta, directorio_origen);
                posicion_inicio = posicion_inicio + length(directorio_origen);
                ruta_intermedia = ruta(posicion_inicio:end);
                archivo_ground_truth = fullfile(directorio_ground_truth, ...
                    ruta_intermedia, [app.nombre_imagen, app.extension]);
                
                % Modifico visibitildad de los objetos de la imagen
                app.Image.Visible = 'off';
                app.UIAxes_original.Visible = 'on';
    
                % Comprobar si existe la imagen ground truth
                if isfile(archivo_ground_truth)
                    % Modifico visibitildad de los objetos de la imagen ground
                    % truth
                    app.Image_2.Visible = 'off';
                    app.UIAxes_ground_truth.Visible = 'on';
                                
                    % Leo la imagen ground truth
                    app.imagen_ground_truth = imread(archivo_ground_truth);
                    app.tiene_img_ground_truth = true;
                    app.CargarimagengroundtruthopcionalButton.Enable = "off";
    
                    % Cambio en el texto del grafico de la imagen ground truth
                    texto_imagen_ground_truth = 'Imagen ground truth';
                    titleString = [texto_imagen_ground_truth ': ' app.nombre_imagen];
        
                    % Evito el que lo interprete como LateX
                    title(app.UIAxes_ground_truth, titleString, 'Interpreter', 'none');
                
                    % Mostrar las imágenes donde corresponde
                    imshow(app.imagen, 'Parent', app.UIAxes_original);
                    imshow(app.imagen_ground_truth, 'Parent', app.UIAxes_ground_truth);
                else
                    % No se encontró imagen ground truth
                    imshow(app.imagen, 'Parent', app.UIAxes_original);
                    app.UIAxes_ground_truth.Visible = 'off';
                    app.CargarimagengroundtruthopcionalButton.Enable = "on";
                    uialert(app.UIFigure, ['Imagen ground truth no encontrada.' ...
                        newline 'Carga una imagen ground truth para poder comparar.'], ...
                        'Imagen ground truth no encontrada');
                end
                
                % Cambiar el texto del botón de carga de imagen
                app.CargarimagendepiezametlicaButton.Text = 'Cargar otra imagen';
                
                % Al ya haber una imagen, habilito el botón de ejecutar
                app.EjecutarButton.Enable = 'on';
                
                % Cerrar waitbar
                close(h);
                
                app.CargarimagendepiezametlicaButton.Enable = "on";
            end
            
            % Desbloqueo opciones
            bloqueoDesbloqueoOpciones(app, 2);

            % Vuelvo a la ventana de la aplicación
            figure(app.UIFigure);
        end

        % Button pushed function: EjecutarButton
        function EjecutarButtonPushed(app, event)
            % Bloqueo opciones
            bloqueoDesbloqueoOpciones(app, 1);

            app.EjecutarButton.Enable = "off";

            % Oculto la ejecución anterior (TODO)
            cla(app.UIAxes_original_resultado);
            cla(app.UIAxes_original_segmentada);
            cla(app.UIAxes_invariante_resultado);
            cla(app.UIAxes_invariante_segmentada);
            app.UIAxes_original_resultado.Title.String = '';
            app.UIAxes_invariante_resultado.Title.String = '';
            app.UIAxes_original_segmentada.Title.String = '';
            app.UIAxes_invariante_segmentada.Title.String = '';
            % Impido que se puedan pulsar botones mientras la ejecución
            app.CargarimagendepiezametlicaButton.Enable = 'off';
            app.CargarimagengroundtruthopcionalButton.Enable = 'off';
            app.GuardarresultadosButton.Enable = 'off';

            % Inicializar la waitbar
            h = waitbar(0, 'Preparando ejecución...', 'Name', 'Ejecución de algoritmos');
            pause(2);

            % Obtengo la imagen de tres canales
            app.imagen_tres_canales = ImagenTresCanales(app.imagen);

            % Selector de invariantes
            if app.AlvarezButton.Value == 1
                app.invariante = 'Alvarez';
                opcion1 = 1;
            elseif app.MaddernButton.Value == 1
                app.invariante = 'Maddern';
                opcion1 = 2;
            elseif app.KrajnkButton.Value == 1
                app.invariante = 'Krajnık';
                opcion1 = 3;
            elseif app.UpcrofButton.Value == 1
                app.invariante = 'Upcrof';
                opcion1 = 4;
            elseif app.PCAButton.Value == 1
                app.invariante = 'PCA';
                opcion1 = 5;
            end

            % Selector de agrupamientos
            if app.KMeansButton.Value == 1
                app.agrupamiento = 'K-Means';
                opcion2 = 1;
            elseif app.FuzzyCMeansButton.Value == 1
                app.agrupamiento = 'C-Means';
                opcion2 = 2;
            elseif app.GaussianMixturesButton.Value == 1
                app.agrupamiento = 'GMM';
                opcion2 = 3;
            elseif app.InformacinespacialButton.Value == 1
                app.agrupamiento = 'Spatial';
                opcion2 = 4;
            end

            % Selector de clusters
            app.numClusters = app.NmerodecentrosEditField.Value;
            
            waitbar(0.15, h, 'Comprobando caché...');
            [existe_invariante, app.imagen_invariante, ~] = check_cache(1, app.nombre_imagen, app.invariante, app.agrupamiento, app.numClusters, app.extension, app.cachePath);
            [existe_original_agrupada, app.imagen_original_agrup, app.porcentaje_original] = check_cache(2, app.nombre_imagen, app.invariante, app.agrupamiento, app.numClusters, app.extension, app.cachePath);
            [existe_invariante_agrupada, app.imagen_invariante_agrup, app.porcentaje_invariante] = check_cache(3, app.nombre_imagen, app.invariante, app.agrupamiento, app.numClusters, app.extension, app.cachePath);

            if ~existe_invariante
                % Actualizar la waitbar
                waitbar(0.25, h, ['Aplicando ' app.invariante ' sobre la imagen...']);
                % Ejecutar algoritmo de transformación invariante
                app.imagen_invariante = metodos_invariantes_app(opcion1, app.imagen_tres_canales);
            end
            
            if ~existe_original_agrupada
                % Actualizar la waitbar
                waitbar(0.5, h, ['Aplicando ' app.agrupamiento ' sobre la imagen original...']);
                set(0, 'DefaultFigureVisible', 'off');
                [app.imagen_original_agrup, app.porcentaje_original] = metodos_agrupamiento(opcion2, app.imagen_tres_canales, app.imagen_ground_truth, app.numClusters);
                set(0, 'DefaultFigureVisible', 'on');
            end

            if ~existe_invariante_agrupada
                % Actualizar la waitbar
                waitbar(0.7, h, ['Aplicando ' app.agrupamiento ' sobre la imagen invariante...']);
                % Preparacion de imagen invariante de tres canales
                imagen_invariante_tres = cat(3, app.imagen_invariante, app.imagen_invariante, app.imagen_invariante);
                set(0, 'DefaultFigureVisible', 'off');
                [app.imagen_invariante_agrup, app.porcentaje_invariante] = metodos_agrupamiento(opcion2, imagen_invariante_tres, app.imagen_ground_truth, app.numClusters);
                set(0, 'DefaultFigureVisible', 'on');
            end

            % Guardado en cache
            waitbar(0.9, h, 'Guardando imagenes en caché...');

            currentDateTime = datetime('now', 'Format', 'yyyyMMddHHmmss');
            fecha = char(currentDateTime);

            suffix_original = ['_' num2str(app.numClusters) '_c'];
            suffix_invariante = ['_' num2str(app.numClusters) '_c'];
            if app.tiene_img_ground_truth
                suffix_original = [suffix_original '_' num2str(app.porcentaje_original) '%'];
                suffix_invariante = [suffix_invariante '_' num2str(app.porcentaje_invariante) '%'];
            end

            imwrite(app.imagen_invariante, fullfile(app.cachePath, [fecha '_1__' app.nombre_imagen '__' app.invariante app.extension]));
            imwrite(app.imagen_original_agrup, fullfile(app.cachePath, [fecha '_2__' app.nombre_imagen '__Original_' app.agrupamiento suffix_original app.extension]));
            imwrite(app.imagen_invariante_agrup, fullfile(app.cachePath, [fecha '_3__' app.nombre_imagen '__' app.invariante '_' app.agrupamiento suffix_invariante app.extension]));

            addImageToUITable(app, app.nombre_imagen, fecha, app.invariante, '', '', '');
            if app.tiene_img_ground_truth
                addImageToUITable(app, app.nombre_imagen, fecha, '', app.agrupamiento, app.numClusters, app.porcentaje_original);
                addImageToUITable(app, app.nombre_imagen, fecha, app.invariante, app.agrupamiento, app.numClusters, app.porcentaje_invariante);
            else
                addImageToUITable(app, app.nombre_imagen, fecha, '', app.agrupamiento, app.numClusters, '');
                addImageToUITable(app, app.nombre_imagen, fecha, app.invariante, app.agrupamiento, app.numClusters, '');
            end

            % Muestro las imágenes
            imshow(app.imagen_original, 'Parent', app.UIAxes_original_resultado);
            imshow(app.imagen_original_agrup, 'Parent', app.UIAxes_original_segmentada);
            imshow(app.imagen_invariante, 'Parent', app.UIAxes_invariante_resultado);
            imshow(app.imagen_invariante_agrup, 'Parent', app.UIAxes_invariante_segmentada);
            app.UIAxes_original_resultado.Title.String = 'Imagen original';
            app.UIAxes_invariante_resultado.Title.String = 'Imagen invariante';
            if app.tiene_img_ground_truth
                app.UIAxes_original_segmentada.Title.String = ['Acierto: ' num2str(app.porcentaje_original, '%.2f') '%'];
                app.UIAxes_invariante_segmentada.Title.String = ['Acierto: ' num2str(app.porcentaje_invariante, '%.2f') '%'];
            else
                app.UIAxes_original_segmentada.Title.String = 'Original agrupada';
                app.UIAxes_invariante_segmentada.Title.String = 'Invariante agrupada';
            end
            app.EjecutarButton.Text = 'Ejecutar de nuevo';
            app.GuardarresultadosButton.Text = 'Guardar resultados';
            app.GuardarresultadosButton.Enable = 'on';

            waitbar(1, h, 'Proceso completado.');
            close(h);

            % Al ya haber resultados, habilito el boton de guardar
            app.CargarimagendepiezametlicaButton.Enable = 'on';
            if app.tiene_img_ground_truth
                app.CargarimagengroundtruthopcionalButton.Enable = 'off';
            else
                app.CargarimagengroundtruthopcionalButton.Enable = 'on';
            end
            app.EjecutarButton.Enable = 'on';
            app.GuardarresultadosButton.Enable = 'on';

            % Desbloqueo opciones
            bloqueoDesbloqueoOpciones(app, 2);
        end

        % Button pushed function: GuardarresultadosButton
        function GuardarresultadosButtonPushed(app, event)
            % Bloqueo opciones
            bloqueoDesbloqueoOpciones(app, 1);
            
            app.GuardarresultadosButton.Enable = "off";
            % Define una ubicación por defecto
            raiz = fileparts(fileparts(app.UIFigure.FileName));
            ubicacion_por_defecto = fullfile(raiz, 'results', 'AppTest');
            
            % Abre un cuadro de diálogo para que el usuario elija una ubicación
            ubicacion = uigetdir(ubicacion_por_defecto, 'Seleccione una ubicación para guardar las imágenes');
            
            % Vuelvo a la ventana de la aplicación
            figure(app.UIFigure);

            % Si el usuario presiona cancelar, uigetdir retorna 0
            if ubicacion == 0
                uialert(app.UIFigure, ['No seleccionó una ubicación, se ' ...
                    'usarán los valores por defecto.'], 'Ubicación no seleccionada');
                ubicacion = ubicacion_por_defecto;
            end
            
            % Inicializa la waitbar
            h = waitbar(0, 'Guardando imágenes...', 'Name', 'Guardado de imágenes'); 
            
            try
                currentDateTime = datetime('now', 'Format', 'yyyyMMddHHmmss');
                fecha = char(currentDateTime);

                switch app.agrupamiento
                    case 'K-Means'
                        agrup = 'KM';
                    case 'C-Means'
                        agrup = 'CM';
                    case 'GMM'
                        agrup = 'GM';
                    case 'Spatial'
                        agrup = 'SP';
                end

                % Guarda las imágenes y actualiza la waitbar
                waitbar(0.25, h, 'Guardando imagen original...');
                imwrite(app.imagen_original, fullfile(ubicacion, [fecha app.extension]));
                
                waitbar(0.50, h, ['Guardando imagen original agrupada (' app.agrupamiento newline ' con ' app.numClusters ' centros) ...']);
                if app.tiene_img_ground_truth
                    imwrite(app.imagen_original_agrup, fullfile(ubicacion, [fecha '_Ori_' agrup '_' num2str(app.numClusters) 'c_' num2str(round(app.porcentaje_original * 100)) app.extension]));
                else
                    imwrite(app.imagen_original_agrup, fullfile(ubicacion, [fecha '_Ori_' agrup '_' num2str(app.numClusters) 'c' app.extension]));
                end

                waitbar(0.75, h, ['Guardando imagen invariante (' app.invariante ')...']);
                imwrite(app.imagen_invariante, fullfile(ubicacion, [fecha '_' app.invariante(1:3) app.extension]));
                
                waitbar(1.0, h, ['Guardando imagen invariante (' app.invariante ') ' newline ' agrupada (' app.agrupamiento ' con ' app.numClusters ' centros)...']);
                if app.tiene_img_ground_truth
                    imwrite(app.imagen_invariante_agrup, fullfile(ubicacion, [fecha '_' app.invariante(1:3) '_' agrup '_' num2str(app.numClusters) 'c_' num2str(round(app.porcentaje_original * 100)) app.extension]));
                else
                    imwrite(app.imagen_invariante_agrup, fullfile(ubicacion, [fecha '_' app.invariante(1:3) '_' agrup '_' num2str(app.numClusters) 'c' app.extension]));
                end

                % Cerrar waitbar
                close(h);
            
                % Mensaje de confirmación
                uialert(app.UIFigure, 'Todas las imágenes se han guardado correctamente.', 'Guardado Exitoso', 'Icon', 'success');
            
                % Cambia el estado y texto del botón
                app.GuardarresultadosButton.Enable = 'off';
                app.GuardarresultadosButton.Text = 'Imágenes Guardadas';
            catch e
                % Cerrar waitbar si hay error
                close(h);
                
                % Mensaje de error
                uialert(app.UIFigure, ['Error al guardar imágenes: ' e.message], 'Error de Guardado');
            
                % Restablecer el botón
                app.GuardarresultadosButton.Enable = 'on';
                app.GuardarresultadosButton.Text = 'Intentar Guardar Nuevamente';
            end

            % Desbloqueo opciones
            bloqueoDesbloqueoOpciones(app, 2);
        end

        % Button pushed function: CargarimagengroundtruthopcionalButton
        function CargarimagengroundtruthopcionalButtonPushed(app, event)
            % Bloqueo opciones
            bloqueoDesbloqueoOpciones(app, 1);

            app.CargarimagengroundtruthopcionalButton.Enable = "off";
            % Abre un cuadro de diálogo para seleccionar la imagen 
            % ground truth
            [imagen_gt_seleccionada, ruta_gt] = uigetfile({['*.jpg;*.jpeg;' ...
                '*.png;*.bmp;*.tif;*.tiff'], ['Imagenes de ground truth ' ...
                '(*.jpg, *.jpeg, *.png, *.bmp, *.tif, *.tiff)']}, ...
                'Selecciona una imagen ground truth');
        
            % Vuelvo a la ventana de la aplicación
            figure(app.UIFigure);

            % Si se cancela el cuadro de diálogo
            if isequal(imagen_gt_seleccionada, 0)
                % Desbloqueo opciones
                bloqueoDesbloqueoOpciones(app, 2);
                uialert(app.UIFigure, ['Se ha cancelado la selección de imagen ground truth.' newline 'Carga una imagen ground truth para poder comparar.'], 'Selección de imagen ground truth cancelada');
                app.CargarimagengroundtruthopcionalButton.Text = 'Cargar otra imagen ground truth';
                app.CargarimagengroundtruthopcionalButton.Enable = "on";
                return;
            end
       
            % Intenta leer la imagen ground truth
            try
                app.UIAxes_ground_truth.Visible = 'on';
                app.Image_2.Visible = 'off';

                % Asigna la imagen ground truth a la variable y la muestra
                app.imagen_ground_truth = imread(fullfile(ruta_gt, imagen_gt_seleccionada));

                % Extraigo el nombre y la extensión de la imagen seleccionada.
                [~, nombre_gt, ~] = fileparts(imagen_gt_seleccionada);

                % Cambio en el texto del grafico de la imagen ground truth
                texto_imagen_ground_truth = 'Imagen ground truth';
                titleString = [texto_imagen_ground_truth ': ' nombre_gt];
    
                % Evito el que lo interprete como LateX
                title(app.UIAxes_ground_truth, titleString, 'Interpreter', 'none');

                imshow(app.imagen_ground_truth, 'Parent', app.UIAxes_ground_truth);
                
                % Actualizar estado para indicar que ahora hay una imagen ground truth
                app.tiene_img_ground_truth = true;
                app.CargarimagengroundtruthopcionalButton.Text = 'Cargar otra imagen ground truth';
            catch ME
                uialert(app.UIFigure, ['Error al leer la imagen ground truth: ', ME.message], 'Error de lectura');
            end
            app.CargarimagengroundtruthopcionalButton.Enable = "on";

            % Desbloqueo opciones
            bloqueoDesbloqueoOpciones(app, 2);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            selection = uiconfirm(app.UIFigure, '¿Estás seguro que deseas salir?', ...
                                  'Confirmar Cierre', ...
                                  'Options', {'Salir', 'Cancelar'}, ...
                                  'DefaultOption', 1, 'CancelOption', 2);
            if strcmp(selection, 'Salir')
                drawnow;
                delete(app);
            end
        end

        % Button pushed function: AbrirdirectorioButton
        function AbrirdirectorioButtonPushed(app, event)
            % Abre la carpeta de la cache
            if ispc
                winopen(app.cachePath);
            elseif ismac
                system(['open ', app.cachePath]);
            elseif isunix
                system(['xdg-open ', app.cachePath]);
            else
                msgbox('No se puede abrir la carpeta de caché en este sistema operativo', 'Error', 'error');
            end
        end

        % Value changed function: NmerodecentrosEditField
        function NmerodecentrosEditFieldValueChanged(app, event)
            value = app.NmerodecentrosEditField.Value;

            if value < 2
                uialert(app.UIFigure, ['La cantidad de centros introducida (' num2str(value) ') es menor al número mínimo, 2'], 'Error en Número de Centros');
                app.NmerodecentrosEditField.Value = 2;
            elseif value > 10
                uialert(app.UIFigure, ['La cantidad de centros introducida (' num2str(value) ') es muy elevada, el valor recomendado es entre 2 y 5'], 'Error en Número de Centros');
                app.NmerodecentrosEditField.Value = 2;
            elseif value > 5
                uialert(app.UIFigure, ['La cantidad de centros introducida (' num2str(value) ') es elevada, el valor recomendado es entre 2 y 5'], 'Error en Número de Centros');
                % No cambiamos el valor, simplemente damos una advertencia
            end
            
            if value >= 2 && value < 10
                app.numClusters = value;
            end
        end

        % Button pushed function: BorrarCacheButton
        function BorrarCacheButtonPushed(app, event)
            cacheFiles = dir(fullfile(app.cachePath, '*.*'));
            cacheFiles = cacheFiles(~ismember({cacheFiles.name}, {'.', '..'}));
            
            for cacheFile = cacheFiles'
                delete(fullfile(app.cachePath, cacheFile.name));
            end
            
            % Vaciar la tabla en la interfaz de usuario
            app.UITable.Data = table('Size', [0 7], ...
                                     'VariableTypes', {'datetime', 'string', 'string', 'string', 'string', 'string', 'string'}, ...
                                     'VariableNames', {'Fecha', 'NombreFichero', 'AlgoritmoInvariante', 'AlgoritmoAgrupamiento', 'NumeroCentros', 'ImagenGroundTruth', 'MedidaDeCalidad'});

            % Mostrar alerta
            uialert(app.UIFigure, 'Cache borrada exitosamente.', 'Alerta');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout10 = uigridlayout(app.GridLayout);
            app.TabGroup = uitabgroup(app.GridLayout10);
            app.BienvenidaTab = uitab(app.TabGroup);
            app.BienvenidaTab.Title = 'Bienvenida';
            app.GridLayout9 = uigridlayout(app.BienvenidaTab);
            app.HTML_Inicio = uihtml(app.GridLayout9);
            app.ExploraciondealgoritmosTab = uitab(app.TabGroup);
            app.ExploraciondealgoritmosTab.Title = 'Exploración de algoritmos';
            app.GridLayout27 = uigridlayout(app.ExploraciondealgoritmosTab);
            app.ResultadosPanel = uipanel(app.GridLayout27);
            app.ResultadosPanel.Title = 'Resultados';
            app.GridLayout3 = uigridlayout(app.ResultadosPanel);
            app.UIAxes_original_resultado = uiaxes(app.GridLayout3);
            title(app.UIAxes_original_resultado, 'Imagen original')
            app.UIAxes_original_resultado.XTick = [];
            app.UIAxes_original_resultado.YTick = [];
            app.UIAxes_original_segmentada = uiaxes(app.GridLayout3);
            title(app.UIAxes_original_segmentada, 'Original segmentada')
            zlabel(app.UIAxes_original_segmentada, 'Z')
            app.UIAxes_original_segmentada.XTick = [];
            app.UIAxes_original_segmentada.YTick = [];
            app.UIAxes_invariante_resultado = uiaxes(app.GridLayout3);
            title(app.UIAxes_invariante_resultado, 'Imagen invariante')
            zlabel(app.UIAxes_invariante_resultado, 'Z')
            app.UIAxes_invariante_resultado.XTick = [];
            app.UIAxes_invariante_resultado.YTick = [];
            app.UIAxes_invariante_segmentada = uiaxes(app.GridLayout3);
            title(app.UIAxes_invariante_segmentada, 'Invariante segmentada')
            zlabel(app.UIAxes_invariante_segmentada, 'Z')
            app.UIAxes_invariante_segmentada.XTick = [];
            app.UIAxes_invariante_segmentada.YTick = [];
            app.GuardarresultadosButton = uibutton(app.GridLayout27, 'push');
            app.GuardarresultadosButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarresultadosButtonPushed, true);
            app.GuardarresultadosButton.Text = 'Guardar resultados';
            app.EjecutarButton = uibutton(app.GridLayout27, 'push');
            app.EjecutarButton.ButtonPushedFcn = createCallbackFcn(app, @EjecutarButtonPushed, true);
            app.EjecutarButton.Text = 'Ejecutar';
            app.GridLayout28 = uigridlayout(app.GridLayout27);
            app.GridLayout29 = uigridlayout(app.GridLayout28);
            app.MtodoinvarianteButtonGroup = uibuttongroup(app.GridLayout29);
            app.MtodoinvarianteButtonGroup.Title = 'Método invariante';
            app.AlvarezButton = uiradiobutton(app.MtodoinvarianteButtonGroup);
            app.AlvarezButton.Text = 'Alvarez';
            app.AlvarezButton.Value = true;
            app.MaddernButton = uiradiobutton(app.MtodoinvarianteButtonGroup);
            app.MaddernButton.Text = 'Maddern';
            app.KrajnkButton = uiradiobutton(app.MtodoinvarianteButtonGroup);
            app.KrajnkButton.Text = 'Krajnık';
            app.UpcrofButton = uiradiobutton(app.MtodoinvarianteButtonGroup);
            app.UpcrofButton.Text = 'Upcrof';
            app.PCAButton = uiradiobutton(app.MtodoinvarianteButtonGroup);
            app.PCAButton.Text = 'PCA';
            app.GridLayout30 = uigridlayout(app.GridLayout29);
            app.MtododeagrupamientoButtonGroup = uibuttongroup(app.GridLayout30);
            app.MtododeagrupamientoButtonGroup.Title = 'Método de agrupamiento';
            app.KMeansButton = uiradiobutton(app.MtododeagrupamientoButtonGroup);
            app.KMeansButton.Text = 'K-Means';
            app.KMeansButton.Value = true;
            app.FuzzyCMeansButton = uiradiobutton(app.MtododeagrupamientoButtonGroup);
            app.FuzzyCMeansButton.Text = 'Fuzzy C-Means';
            app.GaussianMixturesButton = uiradiobutton(app.MtododeagrupamientoButtonGroup);
            app.GaussianMixturesButton.Text = 'Gaussian Mixtures';
            app.InformacinespacialButton = uiradiobutton(app.MtododeagrupamientoButtonGroup);
            app.InformacinespacialButton.Text = 'Información espacial';
            app.Panel_2 = uipanel(app.GridLayout30);
            app.GridLayout31 = uigridlayout(app.Panel_2);
            app.NmerodecentrosLabel = uilabel(app.GridLayout31);
            app.NmerodecentrosLabel.Text = 'Número de centros';
            app.NmerodecentrosEditField = uieditfield(app.GridLayout31, 'numeric');
            app.NmerodecentrosEditField.Limits = [2 Inf];
            app.NmerodecentrosEditField.RoundFractionalValues = 'on';
            app.NmerodecentrosEditField.ValueChangedFcn = createCallbackFcn(app, @NmerodecentrosEditFieldValueChanged, true);
            app.NmerodecentrosEditField.Value = 2;
            app.Panel = uipanel(app.GridLayout28);
            app.GridLayout32 = uigridlayout(app.Panel);
            app.UIAxes_original = uiaxes(app.GridLayout32);
            title(app.UIAxes_original, 'Imagen original')
            app.UIAxes_original.XTick = [];
            app.UIAxes_original.XTickLabel = '';
            app.UIAxes_original.YTick = [];
            app.UIAxes_original.YTickLabel = '';
            app.UIAxes_ground_truth = uiaxes(app.GridLayout32);
            title(app.UIAxes_ground_truth, 'Imagen ground truth')
            app.UIAxes_ground_truth.XTick = [];
            app.UIAxes_ground_truth.XTickLabel = '';
            app.UIAxes_ground_truth.YTick = [];
            app.UIAxes_ground_truth.YTickLabel = '';
            app.Image = uiimage(app.GridLayout32);
            app.Image.Enable = 'off';
            app.CargarimagendepiezametlicaButton = uibutton(app.GridLayout32, 'push');
            app.CargarimagendepiezametlicaButton.ButtonPushedFcn = createCallbackFcn(app, @CargarimagendepiezametlicaButtonPushed, true);
            app.CargarimagendepiezametlicaButton.Text = 'Cargar imagen de pieza metálica';
            app.Image_2 = uiimage(app.GridLayout32);
            app.Image_2.Enable = 'off';
            app.CargarimagengroundtruthopcionalButton = uibutton(app.GridLayout32, 'push');
            app.CargarimagengroundtruthopcionalButton.ButtonPushedFcn = createCallbackFcn(app, @CargarimagengroundtruthopcionalButtonPushed, true);
            app.CargarimagengroundtruthopcionalButton.Text = 'Cargar imagen ground truth (opcional)';
            app.CacheTab = uitab(app.TabGroup);
            app.CacheTab.Title = 'Histórico de exploraciones';
            app.GridLayout11 = uigridlayout(app.CacheTab);
            app.UITable = uitable(app.GridLayout11);
            app.UITable.ColumnName = {'Nombre del fichero'; 'Fecha'; 'Algoritmo invariante'; 'Algoritmo de agrupamiento'; 'Número de centros'; 'Imagen ground truth'; 'Medida de calidad'};
            app.UITable.RowName = {};
            app.GridLayout33 = uigridlayout(app.GridLayout11);
            app.AbrirdirectorioButton = uibutton(app.GridLayout33, 'push');
            app.AbrirdirectorioButton.ButtonPushedFcn = createCallbackFcn(app, @AbrirdirectorioButtonPushed, true);
            app.AbrirdirectorioButton.Text = 'Abrir directorio';
            app.BorrarCacheButton = uibutton(app.GridLayout33, 'push');
            app.BorrarCacheButton.ButtonPushedFcn = createCallbackFcn(app, @BorrarCacheButtonPushed, true);
            app.BorrarCacheButton.Text = 'Borrar Datos';
            app.AyudaTab = uitab(app.TabGroup);
            app.AyudaTab.Title = 'Ayuda';
            app.GridLayout6 = uigridlayout(app.AyudaTab);
            app.Menu_Ayuda = uitabgroup(app.GridLayout6);
            app.FuncionamientoTab = uitab(app.Menu_Ayuda);
            app.FuncionamientoTab.Title = 'Funcionamiento';
            app.GridLayout26 = uigridlayout(app.FuncionamientoTab);
            app.HTML_funcionamiento = uihtml(app.GridLayout26);
            app.MetodosInvariantesTab = uitab(app.Menu_Ayuda);
            app.MetodosInvariantesTab.Title = 'Métodos invariantes';
            app.GridLayout19 = uigridlayout(app.MetodosInvariantesTab);
            app.TabInvariantesTipos = uitabgroup(app.GridLayout19);
            app.AlvarezTab = uitab(app.TabInvariantesTipos);
            app.AlvarezTab.Title = 'Alvarez';
            app.GridLayout20 = uigridlayout(app.AlvarezTab);
            app.HTML_Alvarez = uihtml(app.GridLayout20);
            app.MaddernTab = uitab(app.TabInvariantesTipos);
            app.MaddernTab.Title = 'Maddern';
            app.GridLayout21 = uigridlayout(app.MaddernTab);
            app.HTML_Maddern = uihtml(app.GridLayout21);
            app.KrajnkTab = uitab(app.TabInvariantesTipos);
            app.KrajnkTab.Title = 'Krajnık';
            app.GridLayout22 = uigridlayout(app.KrajnkTab);
            app.HTML_Krajnik = uihtml(app.GridLayout22);
            app.UpcrofTab = uitab(app.TabInvariantesTipos);
            app.UpcrofTab.Title = 'Upcrof';
            app.GridLayout23 = uigridlayout(app.UpcrofTab);
            app.HTML_Upcrof = uihtml(app.GridLayout23);
            app.PCATab = uitab(app.TabInvariantesTipos);
            app.PCATab.Title = 'PCA';
            app.GridLayout25 = uigridlayout(app.PCATab);
            app.HTML_PCA = uihtml(app.GridLayout25);
            app.MetodosAgrupamientoTab = uitab(app.Menu_Ayuda);
            app.MetodosAgrupamientoTab.Title = 'Métodos de agrupamiento';
            app.GridLayout12 = uigridlayout(app.MetodosAgrupamientoTab);
            app.TabAgrupamientoTipos = uitabgroup(app.GridLayout12);
            app.KMeansTab = uitab(app.TabAgrupamientoTipos);
            app.KMeansTab.Title = 'K-Means';
            app.GridLayout13 = uigridlayout(app.KMeansTab);
            app.HTML_K_Means = uihtml(app.GridLayout13);
            app.CMeansTab = uitab(app.TabAgrupamientoTipos);
            app.CMeansTab.Title = 'C-Means';
            app.GridLayout14 = uigridlayout(app.CMeansTab);
            app.HTML_Fuzzy_C_Means = uihtml(app.GridLayout14);
            app.GMMTab = uitab(app.TabAgrupamientoTipos);
            app.GMMTab.Title = 'GMM';
            app.GridLayout15 = uigridlayout(app.GMMTab);
            app.HTML_GMM = uihtml(app.GridLayout15);
            app.SpatialTab = uitab(app.TabAgrupamientoTipos);
            app.SpatialTab.Title = 'Spatial';
            app.GridLayout16 = uigridlayout(app.SpatialTab);
            app.HTML_Spatial = uihtml(app.GridLayout16);
            app.PorcentajeAciertoTab = uitab(app.Menu_Ayuda);
            app.PorcentajeAciertoTab.Title = 'Porcentaje de acierto';
            app.GridLayout17 = uigridlayout(app.PorcentajeAciertoTab);
            app.HTML_Acierto = uihtml(app.GridLayout17);
            app.MemoriaCacheTab = uitab(app.Menu_Ayuda);
            app.MemoriaCacheTab.Title = 'Memoria caché';
            app.GridLayout18 = uigridlayout(app.MemoriaCacheTab);
            app.HTML_Cache = uihtml(app.GridLayout18);
            app.ContextMenu = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu
            app.CargarimagendepiezametlicaButton.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InvIPM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end