classdef InvIPMtest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        BienvenidaTab                   matlab.ui.container.Tab
        HTML_Inicio                     matlab.ui.control.HTML
        ExploraciondealgoritmosTab      matlab.ui.container.Tab
        CargarimagengroundtruthopcionalButton  matlab.ui.control.Button
        CargarimagendepiezametlicaButton  matlab.ui.control.Button
        GuardarresultadosButton         matlab.ui.control.Button
        ResultadosPanel                 matlab.ui.container.Panel
        UIAxes_invariante_segmentada    matlab.ui.control.UIAxes
        UIAxes_invariante_resultado     matlab.ui.control.UIAxes
        UIAxes_original_segmentada      matlab.ui.control.UIAxes
        UIAxes_original_resultado       matlab.ui.control.UIAxes
        NmerodecentrosEditField         matlab.ui.control.NumericEditField
        NmerodecentrosEditFieldLabel    matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        Image_2                         matlab.ui.control.Image
        MtododeagrupamientoButtonGroup  matlab.ui.container.ButtonGroup
        InformacinespacialButton        matlab.ui.control.RadioButton
        GaussianMixturesButton          matlab.ui.control.RadioButton
        FuzzyCMeansButton               matlab.ui.control.RadioButton
        KMeansButton                    matlab.ui.control.RadioButton
        MtodoinvarianteButtonGroup      matlab.ui.container.ButtonGroup
        PCAButton                       matlab.ui.control.RadioButton
        MaddLightNavButton              matlab.ui.control.RadioButton
        UpcrofButton                    matlab.ui.control.RadioButton
        KrajnkButton                    matlab.ui.control.RadioButton
        MaddernButton                   matlab.ui.control.RadioButton
        AlvarezButton                   matlab.ui.control.RadioButton
        EjecutarButton                  matlab.ui.control.Button
        UIAxes_original                 matlab.ui.control.UIAxes
        UIAxes_ground_truth             matlab.ui.control.UIAxes
        AyudaTab                        matlab.ui.container.Tab
        Menu_Ayuda                      matlab.ui.container.TabGroup
        FuncionamientoTab               matlab.ui.container.Tab
        HTML_funcionamiento             matlab.ui.control.HTML
        MetodosInvariantesTab           matlab.ui.container.Tab
        TabInvariantesTipos             matlab.ui.container.TabGroup
        AlvarezTab                      matlab.ui.container.Tab
        HTML_Alvarez                    matlab.ui.control.HTML
        MaddernTab                      matlab.ui.container.Tab
        HTML_Maddern                    matlab.ui.control.HTML
        KrajnkTab                       matlab.ui.container.Tab
        HTML_Krajnik                    matlab.ui.control.HTML
        UpcrofTab                       matlab.ui.container.Tab
        HTML_Upcrof                     matlab.ui.control.HTML
        Tab5                            matlab.ui.container.Tab
        HTML6                           matlab.ui.control.HTML
        PCATab                          matlab.ui.container.Tab
        HTML_PCA                        matlab.ui.control.HTML
        MetodosAgrupamientoTab          matlab.ui.container.Tab
        TabAgrupamientoTipos            matlab.ui.container.TabGroup
        KMeansTab                       matlab.ui.container.Tab
        HTML_K_Means                    matlab.ui.control.HTML
        FuzzyCMeansTab                  matlab.ui.container.Tab
        HTML_Fuzzy_C_Means              matlab.ui.control.HTML
        GMMTab                          matlab.ui.container.Tab
        HTML_GMM                        matlab.ui.control.HTML
        SpatialTab                      matlab.ui.container.Tab
        HTML_Spatial                    matlab.ui.control.HTML
        PorcentajeAciertoTab            matlab.ui.container.Tab
        HTML_Acierto                    matlab.ui.control.HTML
        MemoriaCacheTab                 matlab.ui.container.Tab
        HTML_Cache                      matlab.ui.control.HTML
        CacheTab                        matlab.ui.container.Tab
        ActualizartablaButton           matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
    end

    % Funciones auxiliares
    properties (Access = public)
        imagen
        imagen_ground_truth
        imagen_tres_canales
        nombre_imagen
        extension
        tipo_agrup
        numClusters
        tipo
        tipo2
        imagen_original
        imagen_original_agrup
        imagen_invariante
        imagen_invariante_agrup
        porcentaje_original
        porcentaje_invariante
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Nombre de la app
            app.UIFigure.Name = 'Invariantes de Iluminación en piezas metálicas';
            
            % Cargo las funciones
            raiz = fileparts(fileparts(app.UIFigure.FileName));
            functionsPath = fullfile(raiz, 'src', 'functions');
            addpath(functionsPath);

            livescriptsPath = fullfile(raiz, 'src', 'livescripts');
            app.HTML_Inicio.HTMLSource = fullfile(livescriptsPath, 'bienvenida.html');
            app.HTML_funcionamiento.HTMLSource = fullfile(livescriptsPath, 'funcionamiento.html');
            app.HTML_Alvarez.HTMLSource = fullfile(livescriptsPath, 'met_inv_alvarez.html');
            app.HTML_Maddern.HTMLSource = fullfile(livescriptsPath, 'met_inv_maddern.html');
            app.HTML_Krajnik.HTMLSource = fullfile(livescriptsPath, 'met_inv_krajnik.html');
            app.HTML_Upcrof.HTMLSource = fullfile(livescriptsPath, 'met_inv_upcroft.html');
            app.HTML_PCA.HTMLSource = fullfile(livescriptsPath, 'met_inv_pca.html');
            app.HTML_K_Means.HTMLSource = fullfile(livescriptsPath, 'met_seg_kmeans.html');
            app.HTML_Fuzzy_C_Means.HTMLSource = fullfile(livescriptsPath, 'met_seg_cmeans.html');
            app.HTML_GMM.HTMLSource = fullfile(livescriptsPath, 'met_seg_gmm.html');
            app.HTML_Spatial.HTMLSource = fullfile(livescriptsPath, 'met_seg_spatial.html');
            app.HTML_Acierto.HTMLSource = fullfile(livescriptsPath, 'porcentaje_acierto.html');
            app.HTML_Cache.HTMLSource = fullfile(livescriptsPath, 'memoria_cache.html');

            % Oculto las graficas donde mostrare las imagenes solución
            app.UIAxes_original.Visible = "off";
            app.UIAxes_ground_truth.Visible = "off";
            app.UIAxes_original_resultado.Visible = "off";
            app.UIAxes_original_segmentada.Visible = "off";
            app.UIAxes_invariante_resultado.Visible = "off";
            app.UIAxes_invariante_segmentada.Visible = "off";

            % Inicialmente no se puede cargar una imagen ground truth, 
            % ni ejecutar ni guardar
            app.CargarimagengroundtruthopcionalButton.Enable = 'off';
            app.EjecutarButton.Enable = 'off';
            app.GuardarresultadosButton.Enable = 'off';
        end

        % Button pushed function: CargarimagendepiezametlicaButton
        function CargarimagendepiezametlicaButtonPushed(app, event)
            % Abro un cuadro de diálogo para seleccionar una imagen.
            raiz = fileparts(fileparts(app.UIFigure.FileName));
            directorio_origen = fullfile(raiz, 'data', 'Imagenes');
            directorio_ground_truth = fullfile(raiz, 'data', 'Imagenes_ground_truth');
            [imagen_seleccionada, ruta] = uigetfile(fullfile(directorio_origen, '*.*'), 'Selecciona una imagen');
            
            % Por si se cancela el cuadro de diálogo.
            if isequal(imagen_seleccionada, 0)
                disp('Selección cancelada');
                return;
            else
                % Extraigo el nombre y la extensión de la imagen seleccionada.
                [~, app.nombre_imagen, app.extension] = fileparts(imagen_seleccionada);
                
                % Leo la imagen desde su ruta completa.
                app.imagen = imread(fullfile(ruta, imagen_seleccionada));
                app.imagen_original = app.imagen;
                
                % Obtengo la ruta de la imagen ground truth en base a la
                % seleccionada por el usuario
                posicion_inicio = strfind(ruta, directorio_origen);
                posicion_inicio = posicion_inicio + length(directorio_origen);
                ruta_intermedia = ruta(posicion_inicio:end);
                ruta_ground_truth = fullfile(directorio_ground_truth, ruta_intermedia);
                archivo_ground_truth = fullfile(ruta_ground_truth, [app.nombre_imagen, app.extension]);

                % Compruebo si existe la imagen ground truth
                if isfile(archivo_ground_truth)
                    % Leo la imagen ground truth
                    app.imagen_ground_truth = imread(archivo_ground_truth);
            
                    % Muestro las imagenes donde corresponde
                    app.Image.Visible = "off";
                    app.Image_2.Visible = "off";
                    imshow(app.imagen,'Parent',app.UIAxes_original);
                    imshow(app.imagen_ground_truth,'Parent',app.UIAxes_ground_truth);
                    app.CargarimagendepiezametlicaButton.Text = 'Cargar otra imagen';

                    % Al ya haber una imagen, habilito el boton de ejecutar
                    app.EjecutarButton.Enable = 'on';
                else
                    app.CargarimagendepiezametlicaButton.Text = 'Carga una imagen de data/';
                end

            end
        end

        % Button pushed function: EjecutarButton
        function EjecutarButtonPushed(app, event)
            % Oculto la ejecución anterior (TODO)
            cla(app.UIAxes_original_resultado);
            cla(app.UIAxes_original_segmentada);
            cla(app.UIAxes_invariante_resultado);
            cla(app.UIAxes_invariante_segmentada);
            app.EjecutarButton.Text = 'Cargando...';
            app.GuardarresultadosButton.Enable = 'on';
            app.GuardarresultadosButton.Text = 'Guardar resultados';

            % Obtengo la imagen de tres canales
            app.imagen_tres_canales = ImagenTresCanales(app.imagen);

            % Calculo el invariante
            if app.AlvarezButton.Value == 1
                app.tipo = 'Alvarez';
                aux = .5;
                app.tipo2 = num2str(aux);
                app.imagen_invariante = metodos_invariantes_app(1, app.imagen_tres_canales, aux);
            elseif app.MaddernButton.Value == 1
                app.tipo = 'Maddern';
                aux = calcular_alpha(620, 540, 470);
                app.tipo2 = num2str(aux);
                app.imagen_invariante = metodos_invariantes_app(2, app.imagen_tres_canales, aux);
            elseif app.KrajnkButton.Value == 1
                app.tipo = 'Krajnık';
                aux = calcular_theta_krajnik(app.imagen_tres_canales);
                app.tipo2 = num2str(aux);
                app.imagen_invariante = metodos_invariantes_app(3, app.imagen_tres_canales, aux);
            elseif app.UpcrofButton.Value == 1
                app.tipo = 'Upcrof';
                aux = calcular_alpha(620, 540, 470);
                app.tipo2 = num2str(aux);
                app.imagen_invariante = metodos_invariantes_app(4, app.imagen_tres_canales, aux);
            elseif app.MaddLightNavButton.Value == 1
                % Will Maddern, Alexander D. Stewart, Colin McManus, Ben Upcroft, Winston Churchill, y Paul Newman
                app.tipo = 'MaddLightNav';
                aux = .2;
                app.tipo2 = num2str(aux);
                app.imagen_invariante = metodos_invariantes_app(5, app.imagen_tres_canales, aux);
            elseif app.PCAButton.Value == 1
                app.tipo = 'PCA';
                aux = 0;
                app.tipo2 = '';
                app.imagen_invariante = metodos_invariantes_app(6, app.imagen_tres_canales, aux);
            end

            % Calculo la segmentación
            app.numClusters = app.NmerodecentrosEditField.Value;
            imagen_invariante_tres = cat(3, app.imagen_invariante, app.imagen_invariante, app.imagen_invariante);

            if app.KMeansButton.Value == 1
                app.tipo_agrup = 'K-Means';
                opcion = 1;
            elseif app.FuzzyCMeansButton.Value == 1
                app.tipo_agrup = 'C-Means';
                opcion = 2;
            elseif app.GaussianMixturesButton.Value == 1
                app.tipo_agrup = 'GMM';
                opcion = 3;
            elseif app.InformacinespacialButton.Value == 1
                app.tipo_agrup = 'Spatial';
                opcion = 4;
            end

            set(0, 'DefaultFigureVisible', 'off');
            [app.imagen_original_agrup, app.porcentaje_original] = metodos_agrupamiento(opcion, app.imagen_tres_canales, app.imagen_ground_truth, app.numClusters);
            [app.imagen_invariante_agrup, app.porcentaje_invariante] = metodos_agrupamiento(opcion, imagen_invariante_tres, app.imagen_ground_truth, app.numClusters);
            set(0, 'DefaultFigureVisible', 'on');

            % Muestro las imágenes
            imshow(app.imagen_original, 'Parent', app.UIAxes_original_resultado);
            imshow(app.imagen_original_agrup, 'Parent', app.UIAxes_original_segmentada);
            imshow(app.imagen_invariante, 'Parent', app.UIAxes_invariante_resultado);
            imshow(app.imagen_invariante_agrup, 'Parent', app.UIAxes_invariante_segmentada);
            app.UIAxes_original_segmentada.Title.String = ['Acierto: ' num2str(app.porcentaje_original, '%.2f') '%'];
            app.UIAxes_invariante_segmentada.Title.String = ['Acierto: ' num2str(app.porcentaje_invariante, '%.2f') '%'];
            app.EjecutarButton.Text = 'Ejecutar de nuevo';

            % Al ya haber resultados, habilito el boton de guardar
            app.GuardarresultadosButton.Enable = 'on';
        end

        % Button pushed function: GuardarresultadosButton
        function GuardarresultadosButtonPushed(app, event)
            % Define una ubicación por defecto
            raiz = fileparts(fileparts(app.UIFigure.FileName));
            ubicacion_por_defecto = fullfile(raiz, 'results', 'AppTest');
            
            % Abre un cuadro de diálogo para que el usuario elija una ubicación
            ubicacion = uigetdir(ubicacion_por_defecto, 'Seleccione una ubicación para guardar las imágenes');
            
            % Si el usuario presiona cancelar, uigetdir retorna 0
            if ubicacion == 0
                ubicacion = ubicacion_por_defecto;
            end
            
            % Guarda las imágenes
            imwrite(app.imagen_original, fullfile(ubicacion, [app.nombre_imagen app.extension]));
            imwrite(app.imagen_original_agrup, fullfile(ubicacion, [app.nombre_imagen '_Original_' num2str(app.porcentaje_original) '%_' app.tipo_agrup '_' num2str(app.numClusters) 'c.jpg']));
            imwrite(app.imagen_invariante, fullfile(ubicacion, [app.nombre_imagen '_' app.tipo app.tipo2 app.extension]));
            imwrite(app.imagen_invariante_agrup, fullfile(ubicacion, [app.nombre_imagen '_' app.tipo '_' num2str(app.porcentaje_invariante) '%_' app.tipo_agrup '_' num2str(app.numClusters) 'c.jpg']));
            
            % Cambia el estado y texto del botón
            app.GuardarresultadosButton.Enable = 'off';
            app.GuardarresultadosButton.Text = 'Imágenes Guardadas';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BienvenidaTab = uitab(app.TabGroup);
            app.BienvenidaTab.Title = 'Bienvenida';
            app.HTML_Inicio = uihtml(app.BienvenidaTab);
            app.ExploraciondealgoritmosTab = uitab(app.TabGroup);
            app.ExploraciondealgoritmosTab.Title = 'Exploración de algoritmos';
            app.UIAxes_ground_truth = uiaxes(app.ExploraciondealgoritmosTab);
            title(app.UIAxes_ground_truth, 'Imagen ground truth')
            app.UIAxes_ground_truth.XTick = [];
            app.UIAxes_ground_truth.XTickLabel = '';
            app.UIAxes_ground_truth.YTick = [];
            app.UIAxes_ground_truth.YTickLabel = '';
            app.UIAxes_original = uiaxes(app.ExploraciondealgoritmosTab);
            title(app.UIAxes_original, 'Imagen original')
            app.UIAxes_original.XTick = [];
            app.UIAxes_original.XTickLabel = '';
            app.UIAxes_original.YTick = [];
            app.UIAxes_original.YTickLabel = '';
            app.EjecutarButton = uibutton(app.ExploraciondealgoritmosTab, 'push');
            app.EjecutarButton.ButtonPushedFcn = createCallbackFcn(app, @EjecutarButtonPushed, true);
            app.EjecutarButton.Text = 'Ejecutar';
            app.MtodoinvarianteButtonGroup = uibuttongroup(app.ExploraciondealgoritmosTab);
            app.MtodoinvarianteButtonGroup.Title = 'Método invariante';
            app.AlvarezButton = uiradiobutton(app.MtodoinvarianteButtonGroup);
            app.AlvarezButton.Text = 'Alvarez';
            app.AlvarezButton.Value = true;
            app.MaddernButton = uiradiobutton(app.MtodoinvarianteButtonGroup);
            app.MaddernButton.Text = 'Maddern';
            app.KrajnkButton = uiradiobutton(app.MtodoinvarianteButtonGroup);
            app.KrajnkButton.Text = 'Krajnık';
            app.UpcrofButton = uiradiobutton(app.MtodoinvarianteButtonGroup);
            app.UpcrofButton.Text = 'Upcrof';
            app.MaddLightNavButton = uiradiobutton(app.MtodoinvarianteButtonGroup);
            app.MaddLightNavButton.Text = 'MaddLightNav';
            app.PCAButton = uiradiobutton(app.MtodoinvarianteButtonGroup);
            app.PCAButton.Text = 'PCA';
            app.MtododeagrupamientoButtonGroup = uibuttongroup(app.ExploraciondealgoritmosTab);
            app.MtododeagrupamientoButtonGroup.Title = 'Método de agrupamiento';
            app.KMeansButton = uiradiobutton(app.MtododeagrupamientoButtonGroup);
            app.KMeansButton.Text = 'K-Means';
            app.KMeansButton.Value = true;
            app.FuzzyCMeansButton = uiradiobutton(app.MtododeagrupamientoButtonGroup);
            app.FuzzyCMeansButton.Text = 'Fuzzy C-Means';
            app.GaussianMixturesButton = uiradiobutton(app.MtododeagrupamientoButtonGroup);
            app.GaussianMixturesButton.Text = 'Gaussian Mixtures';
            app.InformacinespacialButton = uiradiobutton(app.MtododeagrupamientoButtonGroup);
            app.InformacinespacialButton.Text = 'Información espacial';
            app.Image_2 = uiimage(app.ExploraciondealgoritmosTab);
            app.Image_2.Enable = 'off';
            app.Image = uiimage(app.ExploraciondealgoritmosTab);
            app.Image.Enable = 'off';
            app.NmerodecentrosEditFieldLabel = uilabel(app.ExploraciondealgoritmosTab);
            app.NmerodecentrosEditFieldLabel.Text = 'Número de centros';
            app.NmerodecentrosEditField = uieditfield(app.ExploraciondealgoritmosTab, 'numeric');
            app.NmerodecentrosEditField.Limits = [2 Inf];
            app.NmerodecentrosEditField.RoundFractionalValues = 'on';
            app.NmerodecentrosEditField.Value = 2;
            app.ResultadosPanel = uipanel(app.ExploraciondealgoritmosTab);
            app.ResultadosPanel.Title = 'Resultados';
            app.UIAxes_original_resultado = uiaxes(app.ResultadosPanel);
            title(app.UIAxes_original_resultado, 'Imagen original')
            app.UIAxes_original_resultado.XTick = [];
            app.UIAxes_original_resultado.YTick = [];
            app.UIAxes_original_segmentada = uiaxes(app.ResultadosPanel);
            title(app.UIAxes_original_segmentada, 'Original segmentada')
            zlabel(app.UIAxes_original_segmentada, 'Z')
            app.UIAxes_original_segmentada.XTick = [];
            app.UIAxes_original_segmentada.YTick = [];
            app.UIAxes_invariante_resultado = uiaxes(app.ResultadosPanel);
            title(app.UIAxes_invariante_resultado, 'Imagen invariante')
            zlabel(app.UIAxes_invariante_resultado, 'Z')
            app.UIAxes_invariante_resultado.XTick = [];
            app.UIAxes_invariante_resultado.YTick = [];
            app.UIAxes_invariante_segmentada = uiaxes(app.ResultadosPanel);
            title(app.UIAxes_invariante_segmentada, 'Invariante segmentada')
            zlabel(app.UIAxes_invariante_segmentada, 'Z')
            app.UIAxes_invariante_segmentada.XTick = [];
            app.UIAxes_invariante_segmentada.YTick = [];
            app.GuardarresultadosButton = uibutton(app.ExploraciondealgoritmosTab, 'push');
            app.GuardarresultadosButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarresultadosButtonPushed, true);
            app.GuardarresultadosButton.Text = 'Guardar resultados';
            app.CargarimagendepiezametlicaButton = uibutton(app.ExploraciondealgoritmosTab, 'push');
            app.CargarimagendepiezametlicaButton.ButtonPushedFcn = createCallbackFcn(app, @CargarimagendepiezametlicaButtonPushed, true);
            app.CargarimagendepiezametlicaButton.Text = 'Cargar imagen de pieza metálica';
            app.CargarimagengroundtruthopcionalButton = uibutton(app.ExploraciondealgoritmosTab, 'push');
            app.CargarimagengroundtruthopcionalButton.Text = 'Cargar imagen ground truth (opcional)';
            app.AyudaTab = uitab(app.TabGroup);
            app.AyudaTab.Title = 'Ayuda';
            app.Menu_Ayuda = uitabgroup(app.AyudaTab);
            app.FuncionamientoTab = uitab(app.Menu_Ayuda);
            app.FuncionamientoTab.Title = 'Funcionamiento';
            app.HTML_funcionamiento = uihtml(app.FuncionamientoTab);
            app.MetodosInvariantesTab = uitab(app.Menu_Ayuda);
            app.MetodosInvariantesTab.Title = 'Métodos invariantes';
            app.TabInvariantesTipos = uitabgroup(app.MetodosInvariantesTab);
            app.AlvarezTab = uitab(app.TabInvariantesTipos);
            app.AlvarezTab.Title = 'Alvarez';
            app.HTML_Alvarez = uihtml(app.AlvarezTab);
            app.MaddernTab = uitab(app.TabInvariantesTipos);
            app.MaddernTab.Title = 'Maddern';
            app.HTML_Maddern = uihtml(app.MaddernTab);
            app.KrajnkTab = uitab(app.TabInvariantesTipos);
            app.KrajnkTab.Title = 'Krajnık';
            app.HTML_Krajnik = uihtml(app.KrajnkTab);
            app.UpcrofTab = uitab(app.TabInvariantesTipos);
            app.UpcrofTab.Title = 'Upcrof';
            app.HTML_Upcrof = uihtml(app.UpcrofTab);
            app.Tab5 = uitab(app.TabInvariantesTipos);
            app.Tab5.Title = 'Tab5';
            app.HTML6 = uihtml(app.Tab5);
            app.PCATab = uitab(app.TabInvariantesTipos);
            app.PCATab.Title = 'PCA';
            app.HTML_PCA = uihtml(app.PCATab);
            app.MetodosAgrupamientoTab = uitab(app.Menu_Ayuda);
            app.MetodosAgrupamientoTab.Title = 'Métodos de agrupamiento';
            app.TabAgrupamientoTipos = uitabgroup(app.MetodosAgrupamientoTab);
            app.KMeansTab = uitab(app.TabAgrupamientoTipos);
            app.KMeansTab.Title = 'K-Means';
            app.HTML_K_Means = uihtml(app.KMeansTab);
            app.FuzzyCMeansTab = uitab(app.TabAgrupamientoTipos);
            app.FuzzyCMeansTab.Title = 'Fuzzy C-Means';
            app.HTML_Fuzzy_C_Means = uihtml(app.FuzzyCMeansTab);
            app.GMMTab = uitab(app.TabAgrupamientoTipos);
            app.GMMTab.Title = 'GMM';
            app.HTML_GMM = uihtml(app.GMMTab);
            app.SpatialTab = uitab(app.TabAgrupamientoTipos);
            app.SpatialTab.Title = 'Spatial';
            app.HTML_Spatial = uihtml(app.SpatialTab);
            app.PorcentajeAciertoTab = uitab(app.Menu_Ayuda);
            app.PorcentajeAciertoTab.Title = 'Porcentaje de acierto';
            app.HTML_Acierto = uihtml(app.PorcentajeAciertoTab);
            app.MemoriaCacheTab = uitab(app.Menu_Ayuda);
            app.MemoriaCacheTab.Title = 'Memoria caché';
            app.HTML_Cache = uihtml(app.MemoriaCacheTab);
            app.CacheTab = uitab(app.TabGroup);
            app.CacheTab.Title = 'Histórico de exploraciones';
            app.UITable = uitable(app.CacheTab);
            app.UITable.ColumnName = {'Nombre del algoritmo invariante'; 'Nombre algoritmo agrupamiento'; 'Número de centros'; 'Nombre fichero'; 'Medida de comparación'; 'Resultado de imágenes detalladas'};
            app.UITable.RowName = {};
            app.ActualizartablaButton = uibutton(app.CacheTab, 'push');
            app.ActualizartablaButton.Text = 'Actualizar tabla';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InvIPMtest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end